classdef SimulationView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulatePlot                    matlab.ui.control.UIAxes
        ChoosephaseportraitButtonGroup  matlab.ui.container.ButtonGroup
        MMIFT4Button                    matlab.ui.control.ToggleButton
        MMIThyroidsizeButton            matlab.ui.control.ToggleButton
        MMITRAbButton                   matlab.ui.control.ToggleButton
        FT4MMIButton                    matlab.ui.control.ToggleButton
        FT4ThyroidsizeButton            matlab.ui.control.ToggleButton
        FT4TRAbButton                   matlab.ui.control.ToggleButton
        ThyroidsizeMMIButton            matlab.ui.control.ToggleButton
        ThyroidsizeFT4Button            matlab.ui.control.ToggleButton
        ThyroidsizeTRAbButton           matlab.ui.control.ToggleButton
        TRAbMMIButton                   matlab.ui.control.ToggleButton
        TRAbThyroidsizeButton           matlab.ui.control.ToggleButton
        TRAbFT4Button                   matlab.ui.control.ToggleButton
        PhasePlot                       matlab.ui.control.UIAxes
        ResultsLabel                    matlab.ui.control.Label
    end


    properties (Access = public)
        T, X, eigen % Description: data matrix to display
    end

    methods ( Static, Access = public )

        function PlotInit(app)
            show_simulation(app.SimulatePlot, app.T,app.X);
            
            show_stability(app.PhasePlot, app.X(:,2),app.X(:,3));
            xlabel(app.PhasePlot,'FT4');
            ylabel(app.PhasePlot,'Thyroid size');
        end
    end


    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SimulatePlot = uiaxes(app.UIFigure);
            title(app.SimulatePlot, 'Title')
            xlabel(app.SimulatePlot, 'X')
            ylabel(app.SimulatePlot, 'Y')
            app.ChoosephaseportraitButtonGroup = uibuttongroup(app.UIFigure);
            app.ChoosephaseportraitButtonGroup.Title = 'Choose phase portrait';
            app.MMIFT4Button = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.MMIFT4Button.Text = 'MMI - FT4';
            app.MMIFT4Button.Value = true;
            app.MMIThyroidsizeButton = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.MMIThyroidsizeButton.Text = 'MMI - Thyroid size';
            app.MMITRAbButton = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.MMITRAbButton.Text = 'MMI - TRAb';
            app.FT4MMIButton = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.FT4MMIButton.Text = 'FT4 - MMI';
            app.FT4ThyroidsizeButton = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.FT4ThyroidsizeButton.Text = 'FT4 - Thyroid size';
            app.FT4TRAbButton = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.FT4TRAbButton.Text = 'FT4- TRAb';
            app.ThyroidsizeMMIButton = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.ThyroidsizeMMIButton.Text = 'Thyroid size - MMI';
            app.ThyroidsizeFT4Button = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.ThyroidsizeFT4Button.Text = 'Thyroid size -  FT4';
            app.ThyroidsizeTRAbButton = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.ThyroidsizeTRAbButton.Text = 'Thyroid size - TRAb';
            app.TRAbMMIButton = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.TRAbMMIButton.Text = 'TRAb - MMI';
            app.TRAbThyroidsizeButton = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.TRAbThyroidsizeButton.Text = 'TRAb - Thyroid size';
            app.TRAbFT4Button = uitogglebutton(app.ChoosephaseportraitButtonGroup);
            app.TRAbFT4Button.Text = 'TRAb - FT4';
            app.PhasePlot = uiaxes(app.UIFigure);
            title(app.PhasePlot, 'Title')
            xlabel(app.PhasePlot, 'X')
            ylabel(app.PhasePlot, 'Y')
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SimulationView

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end